classdef FitnessTracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        FrontPageTab                  matlab.ui.container.Tab
        GetStartedButton              matlab.ui.control.Button
        CreatedbyMathWorksInternshipGroup17Label  matlab.ui.control.Label
        FitnessTrackerLabel_3         matlab.ui.control.Label
        DailyCalorieEstimatorTab      matlab.ui.container.Tab
        WorkoutSummaryButton          matlab.ui.control.Button
        HomeButton                    matlab.ui.control.Button
        DailyCalorieButton            matlab.ui.control.Button
        FoodTrackerButton             matlab.ui.control.Button
        FitnessTrackerLabel_2         matlab.ui.control.Label
        TotalCaloriesBurnedEditField  matlab.ui.control.NumericEditField
        ActivityLevelDropDown         matlab.ui.control.DropDown
        ActivityLevelDropDownLabel    matlab.ui.control.Label
        GenderDropDown                matlab.ui.control.DropDown
        GenderDropDownLabel           matlab.ui.control.Label
        AgeyearsEditField             matlab.ui.control.NumericEditField
        AgeyearsEditFieldLabel        matlab.ui.control.Label
        HeightcmEditField             matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel        matlab.ui.control.Label
        WeightkgEditField             matlab.ui.control.NumericEditField
        WeightkgEditFieldLabel        matlab.ui.control.Label
        TotalCaloriesBurnedEditFieldLabel  matlab.ui.control.Label
        CalculateButton               matlab.ui.control.StateButton
        PleaseenterthefollowinginformationLabel  matlab.ui.control.Label
        FoodTrackerTab                matlab.ui.container.Tab
        WorkoutSummaryButton_2        matlab.ui.control.Button
        HomeButton_2                  matlab.ui.control.Button
        DailyCalorieButton_2          matlab.ui.control.Button
        FoodTrackerButton_2           matlab.ui.control.Button
        TextArea_5                    matlab.ui.control.TextArea
        TextArea_5Label               matlab.ui.control.Label
        TextArea_4                    matlab.ui.control.TextArea
        TextArea_4Label               matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        ProteinEditField              matlab.ui.control.NumericEditField
        ProteinEditFieldLabel         matlab.ui.control.Label
        NameofFoodEditField           matlab.ui.control.EditField
        NameofFoodEditFieldLabel      matlab.ui.control.Label
        TotalCaloriesEditField        matlab.ui.control.NumericEditField
        TotalCaloriesEditFieldLabel   matlab.ui.control.Label
        CaloriesofFoodEditField       matlab.ui.control.NumericEditField
        CaloriesofFoodEditFieldLabel  matlab.ui.control.Label
        FatEditField                  matlab.ui.control.NumericEditField
        FatEditFieldLabel             matlab.ui.control.Label
        CarbohydratesEditField        matlab.ui.control.NumericEditField
        CarbohydratesEditFieldLabel   matlab.ui.control.Label
        PleaseenterthefollowinginformationLabel_2  matlab.ui.control.Label
        CalculateButton_2             matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        WorkoutSummaryTab             matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        StepsField                    matlab.ui.control.Label
        ElevationGainField            matlab.ui.control.Label
        CaloriesField                 matlab.ui.control.Label
        ElevationGainLabel_2          matlab.ui.control.Label
        StepsLabel                    matlab.ui.control.Label
        CaloriesLabel                 matlab.ui.control.Label
        TimeField                     matlab.ui.control.Label
        PaceField                     matlab.ui.control.Label
        DistanceField                 matlab.ui.control.Label
        TimeLabel                     matlab.ui.control.Label
        DistanceLabel                 matlab.ui.control.Label
        PaceLabel                     matlab.ui.control.Label
        WorkoutSummaryLabel           matlab.ui.control.Label
        WorkoutSummaryButton_3        matlab.ui.control.Button
        HomeButton_3                  matlab.ui.control.Button
        DailyCalorieButton_3          matlab.ui.control.Button
        FoodTrackerButton_3           matlab.ui.control.Button
        SelectWorkoutButton           matlab.ui.control.Button
        MapWorkout                    matlab.ui.control.UIAxes
        ActivityBreakdownGraph        matlab.ui.control.UIAxes
        EffortGraph                   matlab.ui.control.UIAxes
        ElevationChart                matlab.ui.control.UIAxes
        Tab                           matlab.ui.container.Tab
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        totalProtein = 0;
        totalCarbs = 0;
        totalFat = 0;
        totalCalories = 0;
        workoutQuery = []; %Store actual workouts 
        workoutLabels = []; %Store name of workouts
        workoutSteps = []; %Store array for number of steps for each workout
        %weight = userData.weight
        globalScores       % for model-predicted scores
        relativeScores     % for user-normalized scores
        stepLabels = categorical.empty;
    end
    
    methods (Access = private)
        function updateEffortGraph(app)
            rawLabels = string(app.workoutLabels);
            
            % Make unique labels if needed
            [uniqueLabels, ~, labelIdx] = unique(rawLabels, 'stable');
            counts = histc(labelIdx, 1:numel(uniqueLabels));
            
            for i = 1:numel(rawLabels)
                if counts(labelIdx(i)) > 1
                    suffix = sum(labelIdx(1:i) == labelIdx(i));  % How many times seen so far
                    rawLabels(i) = rawLabels(i) + " (" + suffix + ")";
                end
            end
            
            labels = categorical(rawLabels);

            G = [app.globalScores(:), app.relativeScores(:)];
            
            cla(app.EffortGraph);
            bar(app.EffortGraph, labels, G, 'grouped');
            legend(app.EffortGraph, {'Global', 'User-relative'}, 'Location', 'northeast');
            title(app.EffortGraph, 'Workout Intensity (Global vs Personalized)');
            xlabel(app.EffortGraph, 'Workouts');
            ylabel(app.EffortGraph, 'Intensity Score');
            ylim(app.EffortGraph, [0 10]);
        end
        
        function relativeScore = getRelativeIntensity(~, newWorkout, userHistory, trainedModel)
            vars = trainedModel.RequiredVariables;
        
            % Means & stds from user history
            means = varfun(@mean, userHistory, 'InputVariables', vars);
            stds  = varfun(@std, userHistory, 'InputVariables', vars);
        
            % Z-score calculation
            z = (newWorkout{1, vars} - means{1, :}) ./ stds{1, :};
            z(isnan(z)) = 0;
            relativeScore = norm(z);
            relativeScore = min(relativeScore, 10);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
            addpath('HelperFunctions/'); %To access helper functions

            %Assign variables and store them into struct
            userData.weight = app.WeightkgEditField.Value;
            userData.height = app.HeightcmEditField.Value;
            userData.age = app.AgeyearsEditField.Value;
            userData.gender = app.GenderDropDown.Value;
            userData.activityLvl = app.ActivityLevelDropDown.Value;
            userData.totalCaloriesEaten = 0;

            %Call function to calculate total calories burned
            app.TotalCaloriesBurnedEditField.Value = calculateTDEE(userData);

            %Save value to struct
            userData.TDEE = app.TotalCaloriesBurnedEditField.Value;

            %Calculate Caloric Deficit and Surplus 
            minimumCal = userData.TDEE * 0.10; %minimum threshold to be in surplus/deficit
            userData.caloricDeficit = userData.TDEE - minimumCal;
            userData.caloricSurplus = userData.TDEE + minimumCal;
            
            %Update the plot for Recommended Caloric Graph
            labels = categorical({'Deficit','Maintanence','Surplus','Current'});
            labels = reordercats(labels,{'Deficit','Maintanence','Surplus','Current'});
            caloricStates = [userData.caloricDeficit userData.TDEE userData.caloricSurplus 1]; 
            bar(app.UIAxes2, labels, caloricStates);
            title(app.UIAxes2, 'Caloric Graph');

            %Save struct as file to use later
            save('userData.mat', 'userData');
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            load("userData.mat","userData")
            %Assign variables and store them in food struct
            foodData.name = app.NameofFoodEditField.Value;
            foodData.protein = app.ProteinEditField.Value;
            foodData.fat = app.FatEditField.Value;
            foodData.carbohydrates = app.CarbohydratesEditField.Value;
            
            %Update total calories eaten
            userData.totalCaloriesEaten = userData.totalCaloriesEaten + calcMacros(foodData);

            %Calculate calories of food and total calories for person
            app.CaloriesofFoodEditField.Value = calcMacros(foodData);
            app.TotalCaloriesEditField.Value = userData.totalCaloriesEaten;
            %app.Slider.Value = userData.totalCaloriesEaten; removed it
            
            %% Update Macronutrients Graph
            % Add current food's macros to cumulative totals
            app.totalProtein = app.totalProtein + foodData.protein;
            app.totalCarbs   = app.totalCarbs   + foodData.carbohydrates;
            app.totalFat     = app.totalFat     + foodData.fat;
            
            % Create matrix of cumulative macros
            macroGrams = [app.totalProtein, app.totalCarbs, app.totalFat];
            
            % Update pie chart
            cla(app.UIAxes);
            pie(app.UIAxes, macroGrams);
            title(app.UIAxes, 'Actual Macronutrients');
            
            %% Update Caloric Graph
            labels = categorical({'Deficit','Maintanence','Surplus','Current'});
            labels = reordercats(labels,{'Deficit','Maintanence','Surplus','Current'});

            cla(app.UIAxes2);
            caloricStates = [userData.caloricDeficit userData.TDEE userData.caloricSurplus userData.totalCaloriesEaten];
            bar(app.UIAxes2, labels, caloricStates)
            title(app.UIAxes2, 'Caloric Graph');

            %% Save foods and total calories for the day
            save("foodData.mat", "foodData");
            save("userData.mat","userData");
        end

        % Button pushed function: DailyCalorieButton, 
        % ...and 3 other components
        function GetStartedButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DailyCalorieEstimatorTab;
        end

        % Button pushed function: FoodTrackerButton, FoodTrackerButton_2, 
        % ...and 1 other component
        function FoodTrackerButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FoodTrackerTab;
        end

        % Button pushed function: HomeButton, HomeButton_2, HomeButton_3
        function HomeButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FrontPageTab;
        end

        % Button pushed function: WorkoutSummaryButton, 
        % ...and 2 other components
        function WorkoutSummaryButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.WorkoutSummaryTab;
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
        end

        % Button pushed function: SelectWorkoutButton
        function SelectWorkoutButtonPushed(app, event)

            %Select file workout to get the summary 
            [file, path] = uigetfile({'Data/MixedActivity/*'}, 'Please select a workout file');
            fullFilePath = fullfile(path, file);

            %Load the userData(weight,height,etc), trainedModel, and access
            %to activity functions
            load("userData.mat","userData");
            model = load('trainedModel.mat'); 
            advancedModel = load('trainedModelAdvanced.mat'); 
            altitudeModel = load('trainedAltitudeModel.mat');
            %model = load('exampleTrainedModel.mat');
            addpath('ActivityFunctions/');

            %% Use train model to create labels
            %Labels will be used to create different visual graphs
            %Acceleration
            activity = load(fullFilePath); %load to get access to Acceleration
            justAcc = timetable2table(activity.Acceleration, "ConvertRowTimes", false); %remove timestamps
            yfit = advancedModel.trainedModelAdvanced.predictFcn(justAcc); % produce labels(vector column) for later use
            %Altitude
            justAlt = timetable2table(activity.Position,"ConvertRowTimes", false);
            yfitAlt = altitudeModel.trainedAltitudeModel.predictFcn(justAlt);

            
            %% Workout Summary
            %Compute for total distance of workout
            distanceField = string(round(getDistance(fullFilePath), 2));
            app.DistanceField.Text = distanceField + " mi";

            %Compute for pace of workout
            paceField = round(calculatePace(fullFilePath),2);
            app.PaceField.Text = decimalToTimeConversion(paceField) + " /mi";

            %Compute for total duration of workout
            timeField = round(getDuration(fullFilePath),2);
            app.TimeField.Text = string(decimalToTimeConversion(timeField)) + "s";

            %Compute total calories burned from a workout
            activity.Acceleration.Activity = yfit;
            totCalBurned = calculateTotCaloriesBurned(userData.weight, activity);
            app.CaloriesField.Text = string(round(totCalBurned,0)) + " Cal";

            %Compute total elevation gain from workout
            elevationGain = getAltitudeGain(fullFilePath, yfitAlt);
            app.ElevationGainField.Text = string(round(elevationGain,0)) + " ft";
            
            %Compute total steps gain from workout
            stepsOfOneWorkout = getTotalSteps(fullFilePath); %func call
            app.StepsField.Text = string(stepsOfOneWorkout);
    
            %Get lat,lon, and alt
            [lat,lon,alt] = getLatLotAlt(fullFilePath);
            
            % Plot the workout map
            cla(app.MapWorkout);
            plot3(app.MapWorkout, lon, lat, alt, 'LineWidth',2);
            view(app.MapWorkout, 2);  % Enables top view
            hold(app.MapWorkout, 'on'); % Hold on to plot start and end points
            plot3(app.MapWorkout, lon(1), lat(1), alt(1), 'oc', ...
                'MarkerFaceColor', 'c', 'MarkerEdgeColor', 'w'); %plot start point
            plot3(app.MapWorkout, lon(end), lat(end), alt(end), 'ok', ...
                'MarkerFaceColor', 'k', 'MarkerEdgeColor', 'w'); %plot end point
            hold(app.MapWorkout, 'off');
            % Remove axis ticks and labels
            app.MapWorkout.XTick = [];
            app.MapWorkout.YTick = [];
            app.MapWorkout.ZTick = []; % Only for 3D
            % Remove axis labels (optional)
            app.MapWorkout.XLabel.String = '';
            app.MapWorkout.YLabel.String = '';
            app.MapWorkout.ZLabel.String = '';

            %% Generate Elevation Graph
            cla(app.ElevationChart);
            
            %%Compute the total distance traveled from lan and lot
            % Calculate distances in meters
            latDistance = diff(lat) * 111139; % Approximate conversion for latitude
            lonDistance = diff(lon) * 111139 .* cosd(lat(1:end-1)); % Adjust for longitude based on latitude
            altitude = activity.Position.altitude .* 3.28084;
            distances = sqrt(latDistance.^2 + lonDistance.^2); % Euclidean distance in meters
            distancesInMiles = distances / 1609.34; % Convert meters to miles
            cumulativeDiff = [0; cumsum(distancesInMiles)];

            plot(app.ElevationChart, cumulativeDiff, altitude, 'm');

            %% Generate a barh graph to represent the intensity level between dif workouts

            % Extract features for this workout
            steps = getTotalSteps(fullFilePath);
            duration = getDuration(fullFilePath);
            distance = getDistance(fullFilePath);
            [avgAccel, maxAccel] = getAvgMaxAccel(fullFilePath);
            altGain = getTotalAltitudeGain(fullFilePath);
            avgPace = (distance * 5280) / (duration * 60);
            
            % Create table for model
            workout = table(steps, duration, distance, avgPace, avgAccel, maxAccel, altGain, ...
                'VariableNames', {'steps','duration','distance','avgPace','avgAccel','maxAccel','altGain'});
            
            % Load model
            modelStruct = load('trainedModel.mat');
            trainedModel = modelStruct.trainedModel;
            
            % Predict global intensity
            globalScore = trainedModel.predictFcn(workout);
            
            % Load or initialize user history
            historyFile = 'userHistory.mat';
            if isfile(historyFile)
                load(historyFile, 'userHistory');
            else
                userHistory = workout([],:); % Empty table with same vars
            end
            
            % Compute relative intensity
            disp("🔍 Current Workout:");
            disp(workout);
            
            disp("📊 User History:");
            disp(userHistory);
            
            relativeScore = app.getRelativeIntensity(workout, userHistory, trainedModel);

            % Append this workout to history
            userHistory = [userHistory; workout];
            save(historyFile, 'userHistory');  % Save updated history
            
            % Extract clean label
            fileLabel = erase(replace(file, "_", " "), ".mat");
            
            % Store in app
            app.workoutLabels = [app.workoutLabels, string(fileLabel)];
            app.globalScores = [app.globalScores, double(globalScore)];
            app.relativeScores = [app.relativeScores, double(relativeScore)];
            
            disp("DEBUGGINGGGG");
            disp("Labels:");
            disp(app.workoutLabels);
            disp("Global Scores:");
            disp(app.globalScores);
            disp("Relative Scores:");
            disp(app.relativeScores);

            % Plot
            updateEffortGraph(app);

            %% Generate other graph
            
            %% Generate activity breakdown pie chart
            yfitcat = categorical(cellstr(yfit)); % convert to correct data type for histogram
            cla(app.ActivityBreakdownGraph)
            pie(app.ActivityBreakdownGraph, yfitcat);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FrontPageTab = uitab(app.TabGroup);
            app.FrontPageTab.Title = 'Front Page';
            app.FitnessTrackerLabel_3 = uilabel(app.FrontPageTab);
            app.FitnessTrackerLabel_3.Text = 'Fitness Tracker';
            app.CreatedbyMathWorksInternshipGroup17Label = uilabel(app.FrontPageTab);
            app.CreatedbyMathWorksInternshipGroup17Label.Text = 'Created by MathWorks Internship Group 17';
            app.GetStartedButton = uibutton(app.FrontPageTab, 'push');
            app.GetStartedButton.ButtonPushedFcn = createCallbackFcn(app, @GetStartedButtonPushed, true);
            app.GetStartedButton.Text = 'Get Started';
            app.DailyCalorieEstimatorTab = uitab(app.TabGroup);
            app.DailyCalorieEstimatorTab.Title = 'Daily Calorie Estimator';
            app.PleaseenterthefollowinginformationLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.PleaseenterthefollowinginformationLabel.Text = 'Please enter the following information';
            app.CalculateButton = uibutton(app.DailyCalorieEstimatorTab, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Calculate';
            app.TotalCaloriesBurnedEditFieldLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.TotalCaloriesBurnedEditFieldLabel.Text = 'Total Calories Burned';
            app.WeightkgEditFieldLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.WeightkgEditFieldLabel.Text = 'Weight (kg)';
            app.WeightkgEditField = uieditfield(app.DailyCalorieEstimatorTab, 'numeric');
            app.HeightcmEditFieldLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.HeightcmEditField = uieditfield(app.DailyCalorieEstimatorTab, 'numeric');
            app.AgeyearsEditFieldLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.AgeyearsEditFieldLabel.Text = 'Age (years)';
            app.AgeyearsEditField = uieditfield(app.DailyCalorieEstimatorTab, 'numeric');
            app.GenderDropDownLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.GenderDropDownLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.DailyCalorieEstimatorTab);
            app.GenderDropDown.Items = {'Male ', 'Female'};
            app.GenderDropDown.Value = 'Male ';
            app.ActivityLevelDropDownLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.ActivityLevelDropDownLabel.Text = 'Activity Level';
            app.ActivityLevelDropDown = uidropdown(app.DailyCalorieEstimatorTab);
            app.ActivityLevelDropDown.Items = {'Sedentary (little to no exercise)', 'Lightly active (light exercise 1–3 days per week)', 'Moderately active (moderate exercise 3–5 days per week)', 'Very active (hard exercise 6–7 days per week)', 'Extra active (very hard exercise two or more times per day, training, or a physical job)'};
            app.ActivityLevelDropDown.Value = 'Sedentary (little to no exercise)';
            app.TotalCaloriesBurnedEditField = uieditfield(app.DailyCalorieEstimatorTab, 'numeric');
            app.TotalCaloriesBurnedEditField.Editable = 'off';
            app.FitnessTrackerLabel_2 = uilabel(app.DailyCalorieEstimatorTab);
            app.FitnessTrackerLabel_2.Text = 'Fitness Tracker';
            app.FoodTrackerButton = uibutton(app.DailyCalorieEstimatorTab, 'push');
            app.FoodTrackerButton.ButtonPushedFcn = createCallbackFcn(app, @FoodTrackerButtonPushed, true);
            app.FoodTrackerButton.Text = 'Food Tracker';
            app.DailyCalorieButton = uibutton(app.DailyCalorieEstimatorTab, 'push');
            app.DailyCalorieButton.ButtonPushedFcn = createCallbackFcn(app, @GetStartedButtonPushed, true);
            app.DailyCalorieButton.Text = 'Daily Calorie';
            app.HomeButton = uibutton(app.DailyCalorieEstimatorTab, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.WorkoutSummaryButton = uibutton(app.DailyCalorieEstimatorTab, 'push');
            app.WorkoutSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @WorkoutSummaryButtonPushed, true);
            app.WorkoutSummaryButton.Text = 'Workout Summary';
            app.FoodTrackerTab = uitab(app.TabGroup);
            app.FoodTrackerTab.Title = 'Food Tracker';
            app.UIAxes = uiaxes(app.FoodTrackerTab);
            title(app.UIAxes, 'Actual Macronutrients')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.FoodTrackerTab);
            title(app.UIAxes2, 'Calorie Comparision')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CalculateButton_2 = uibutton(app.FoodTrackerTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.PleaseenterthefollowinginformationLabel_2 = uilabel(app.FoodTrackerTab);
            app.PleaseenterthefollowinginformationLabel_2.Text = 'Please enter the following information';
            app.CarbohydratesEditFieldLabel = uilabel(app.FoodTrackerTab);
            app.CarbohydratesEditFieldLabel.Text = 'Carbohydrates';
            app.CarbohydratesEditField = uieditfield(app.FoodTrackerTab, 'numeric');
            app.CarbohydratesEditField.AllowEmpty = 'on';
            app.CarbohydratesEditField.Value = [];
            app.FatEditFieldLabel = uilabel(app.FoodTrackerTab);
            app.FatEditFieldLabel.Text = 'Fat';
            app.FatEditField = uieditfield(app.FoodTrackerTab, 'numeric');
            app.FatEditField.AllowEmpty = 'on';
            app.FatEditField.Value = [];
            app.CaloriesofFoodEditFieldLabel = uilabel(app.FoodTrackerTab);
            app.CaloriesofFoodEditFieldLabel.Text = 'Calories of Food';
            app.CaloriesofFoodEditField = uieditfield(app.FoodTrackerTab, 'numeric');
            app.CaloriesofFoodEditField.AllowEmpty = 'on';
            app.CaloriesofFoodEditField.Editable = 'off';
            app.CaloriesofFoodEditField.Value = [];
            app.TotalCaloriesEditFieldLabel = uilabel(app.FoodTrackerTab);
            app.TotalCaloriesEditFieldLabel.Text = 'Total Calories';
            app.TotalCaloriesEditField = uieditfield(app.FoodTrackerTab, 'numeric');
            app.TotalCaloriesEditField.AllowEmpty = 'on';
            app.TotalCaloriesEditField.Editable = 'off';
            app.TotalCaloriesEditField.Value = [];
            app.NameofFoodEditFieldLabel = uilabel(app.FoodTrackerTab);
            app.NameofFoodEditFieldLabel.Text = 'Name of Food';
            app.NameofFoodEditField = uieditfield(app.FoodTrackerTab, 'text');
            app.ProteinEditFieldLabel = uilabel(app.FoodTrackerTab);
            app.ProteinEditFieldLabel.Text = 'Protein';
            app.ProteinEditField = uieditfield(app.FoodTrackerTab, 'numeric');
            app.ProteinEditField.AllowEmpty = 'on';
            app.ProteinEditField.Value = [];
            app.TextArea = uitextarea(app.FoodTrackerTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Protein'};
            app.Label = uilabel(app.FoodTrackerTab);
            app.Label.Text = '.';
            app.TextArea_4Label = uilabel(app.FoodTrackerTab);
            app.TextArea_4Label.Text = '.';
            app.TextArea_4 = uitextarea(app.FoodTrackerTab);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Carbs'};
            app.TextArea_5Label = uilabel(app.FoodTrackerTab);
            app.TextArea_5Label.Text = '.';
            app.TextArea_5 = uitextarea(app.FoodTrackerTab);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'Fats'};
            app.FoodTrackerButton_2 = uibutton(app.FoodTrackerTab, 'push');
            app.FoodTrackerButton_2.ButtonPushedFcn = createCallbackFcn(app, @FoodTrackerButtonPushed, true);
            app.FoodTrackerButton_2.Text = 'Food Tracker';
            app.DailyCalorieButton_2 = uibutton(app.FoodTrackerTab, 'push');
            app.DailyCalorieButton_2.ButtonPushedFcn = createCallbackFcn(app, @GetStartedButtonPushed, true);
            app.DailyCalorieButton_2.Text = 'Daily Calorie';
            app.HomeButton_2 = uibutton(app.FoodTrackerTab, 'push');
            app.HomeButton_2.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton_2.Text = 'Home';
            app.WorkoutSummaryButton_2 = uibutton(app.FoodTrackerTab, 'push');
            app.WorkoutSummaryButton_2.ButtonPushedFcn = createCallbackFcn(app, @WorkoutSummaryButtonPushed, true);
            app.WorkoutSummaryButton_2.Text = 'Workout Summary';
            app.WorkoutSummaryTab = uitab(app.TabGroup);
            app.WorkoutSummaryTab.Title = 'Workout Summary';
            app.Panel = uipanel(app.WorkoutSummaryTab);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.ElevationChart = uiaxes(app.Panel);
            title(app.ElevationChart, 'Elevation Chart')
            xlabel(app.ElevationChart, 'Miles')
            ylabel(app.ElevationChart, 'Feet')
            zlabel(app.ElevationChart, 'Z')
            app.EffortGraph = uiaxes(app.Panel);
            title(app.EffortGraph, 'Analyzing Workout Efforts')
            xlabel(app.EffortGraph, 'Effort Level')
            zlabel(app.EffortGraph, 'Z')
            app.ActivityBreakdownGraph = uiaxes(app.Panel);
            title(app.ActivityBreakdownGraph, 'Activity Breakdown')
            zlabel(app.ActivityBreakdownGraph, 'Z')
            app.MapWorkout = uiaxes(app.Panel);
            zlabel(app.MapWorkout, 'Z')
            app.SelectWorkoutButton = uibutton(app.Panel, 'push');
            app.SelectWorkoutButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWorkoutButtonPushed, true);
            app.SelectWorkoutButton.Text = 'Select Workout';
            app.FoodTrackerButton_3 = uibutton(app.Panel, 'push');
            app.FoodTrackerButton_3.ButtonPushedFcn = createCallbackFcn(app, @FoodTrackerButtonPushed, true);
            app.FoodTrackerButton_3.Text = 'Food Tracker';
            app.DailyCalorieButton_3 = uibutton(app.Panel, 'push');
            app.DailyCalorieButton_3.ButtonPushedFcn = createCallbackFcn(app, @GetStartedButtonPushed, true);
            app.DailyCalorieButton_3.Text = 'Daily Calorie';
            app.HomeButton_3 = uibutton(app.Panel, 'push');
            app.HomeButton_3.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton_3.Text = 'Home';
            app.WorkoutSummaryButton_3 = uibutton(app.Panel, 'push');
            app.WorkoutSummaryButton_3.ButtonPushedFcn = createCallbackFcn(app, @WorkoutSummaryButtonPushed, true);
            app.WorkoutSummaryButton_3.Text = 'Workout Summary';
            app.WorkoutSummaryLabel = uilabel(app.Panel);
            app.WorkoutSummaryLabel.Text = 'Workout Summary';
            app.PaceLabel = uilabel(app.Panel);
            app.PaceLabel.Text = 'Pace: ';
            app.DistanceLabel = uilabel(app.Panel);
            app.DistanceLabel.Text = 'Distance: ';
            app.TimeLabel = uilabel(app.Panel);
            app.TimeLabel.Text = 'Time: ';
            app.DistanceField = uilabel(app.Panel);
            app.DistanceField.Text = '';
            app.PaceField = uilabel(app.Panel);
            app.PaceField.Text = '';
            app.TimeField = uilabel(app.Panel);
            app.TimeField.Text = '';
            app.CaloriesLabel = uilabel(app.Panel);
            app.CaloriesLabel.Text = 'Calories:';
            app.StepsLabel = uilabel(app.Panel);
            app.StepsLabel.Text = 'Steps:';
            app.ElevationGainLabel_2 = uilabel(app.Panel);
            app.ElevationGainLabel_2.Text = 'Elevation Gain:';
            app.CaloriesField = uilabel(app.Panel);
            app.CaloriesField.Text = '';
            app.ElevationGainField = uilabel(app.Panel);
            app.ElevationGainField.Text = '';
            app.StepsField = uilabel(app.Panel);
            app.StepsField.Text = '';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FitnessTracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end